classdef drdoproject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainGridLayout                  matlab.ui.container.GridLayout
        MainTabGroup                    matlab.ui.container.TabGroup
        DetectorTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        AnalysisPanel                   matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ClearButton                     matlab.ui.control.Button
        SaveSnapshotButton              matlab.ui.control.Button
        ThreatIndicatorLamp             matlab.ui.control.Lamp
        ThreatLevelLabel                matlab.ui.control.Label
        ConfidenceGauge                 matlab.ui.control.Gauge
        ConfidenceTitleLabel            matlab.ui.control.Label
        DetectionResultLabel            matlab.ui.control.Label
        DetectionTitleLabel             matlab.ui.control.Label
        AnalyzeSoundButton              matlab.ui.control.Button
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        SpectrogramAxes                 matlab.ui.control.UIAxes
        AudioControlPanel               matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        StopAudioButton                 matlab.ui.control.Button
        PlayAudioButton                 matlab.ui.control.Button
        AudioInfoLabel                  matlab.ui.control.Label
        RecordDurationSpinner           matlab.ui.control.Spinner
        RecordAudioButton               matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        FileNameLabel                   matlab.ui.control.Label
        LoadAudioButton                 matlab.ui.control.Button
        WaveformAxes                    matlab.ui.control.UIAxes
        EvaluationTab                   matlab.ui.container.Tab
        EvaluateButton                  matlab.ui.control.Button
        Panel_16                        matlab.ui.container.Panel
        Panel_15                        matlab.ui.container.Panel
        Panel_14                        matlab.ui.container.Panel
        KNNAccuracyLabel                matlab.ui.control.Label
        KNNAccuracyGauge                matlab.ui.control.Gauge
        KNNAccuracyGaugeLabel           matlab.ui.control.Label
        KNNLabel                        matlab.ui.control.Label
        Panel_13                        matlab.ui.container.Panel
        CNNAccuracyLabel                matlab.ui.control.Label
        CNNAccuracyGauge                matlab.ui.control.Gauge
        CNNLabel                        matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        RandomForestAccuracyLabel       matlab.ui.control.Label
        RandomForestAccuracyGauge       matlab.ui.control.Gauge
        RandomForestLabel               matlab.ui.control.Label
        Panel_17                        matlab.ui.container.Panel
        SVMPrecisionLabel               matlab.ui.control.Label
        SVMAccuracyGauge                matlab.ui.control.Gauge
        SVMAccuracyLabel                matlab.ui.control.Label
        SVMLabel                        matlab.ui.control.Label
        EventLogTab                     matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        ExportLogButton                 matlab.ui.control.Button
        ClearLogButton                  matlab.ui.control.Button
        LogTextArea                     matlab.ui.control.TextArea
        EventLogsLabel                  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        ThresholdAccuracyPanel          matlab.ui.container.Panel
        SaveSettingsButton              matlab.ui.control.Button
        ResettoDefaultsButton           matlab.ui.control.Button
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        ThresholdValueSpinner           matlab.ui.control.Spinner
        ThresholdValueLabel             matlab.ui.control.Label
        SelectManuallyButton_2          matlab.ui.control.RadioButton
        Autoselect80Button              matlab.ui.control.RadioButton
        ModelSelectionPanel             matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        KNNButton                       matlab.ui.control.RadioButton
        LSTMButton                      matlab.ui.control.RadioButton
        CNNButton                       matlab.ui.control.RadioButton
        RandomForestButton              matlab.ui.control.RadioButton
        SVMButton                       matlab.ui.control.RadioButton
        AvailableModelsLabel            matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SelectManuallyButton            matlab.ui.control.RadioButton
        AutoselectbestmodelButton_2     matlab.ui.control.RadioButton
        AboutTab                        matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Home                            matlab.ui.container.Tab
        SoundIntrusionSystemLabel       matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel  matlab.ui.control.Label
        ConfigurableParametersLabel     matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        ComprehensivedataanalysisandvisulizationforsecurityassessmentLabel_3  matlab.ui.control.Label
        AnalyticsReportingLabel_3       matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel_3  matlab.ui.control.Label
        ThreatDetectionLabel_3          matlab.ui.control.Label
        Panel_9                         matlab.ui.container.Panel
        AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel_3  matlab.ui.control.Label
        RealtimeProcessingLabel_3       matlab.ui.control.Label
        CoreCapabilitiesLabel_3         matlab.ui.control.Label
        LearnMoreAboutTheProjectButton  matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        AuraSieldLabel                  matlab.ui.control.Label
        AboutProject                    matlab.ui.container.Tab
        DEVELOPMENTTEAMPanel            matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        PriyanshuSrivastavaLabel        matlab.ui.control.Label
        AmitanshSrivastavaLabel         matlab.ui.control.Label
        AdityaRajSrivastavaLabel        matlab.ui.control.Label
        TejasPalLabel                   matlab.ui.control.Label
        AyushiMishraLabel               matlab.ui.control.Label
        AnshitaKapkotiLabel             matlab.ui.control.Label
        PROJECTHIGHLIGHTSPanel          matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        ScalablearchitecturesupportingmultiplesensornetworksLabel  matlab.ui.control.Label
        IntegrationcapabilitieswithexistingsecurityinfrastructureLabel  matlab.ui.control.Label
        MachinelearningalgorithmsforimproveddetectionaccuracyLabel  matlab.ui.control.Label
        ComprehensiveloggingandaudittrailfunctionalityLabel  matlab.ui.control.Label
        RealtimevisualizationofacousticdataandthreatlevelsLabel  matlab.ui.control.Label
        MATLABbasedimplementationforrobustsignalprocessingLabel  matlab.ui.control.Label
        ComparisonTab                   matlab.ui.container.Tab
        HeaderPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DateTimeLabel                   matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
        SystemStatusLamp                matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
        LogoImage                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        AudioData % Stores audio signal
        Fs % Stores sampling rate
        AudioPlayer % For audioplayer object
        IsPlaying = false;
        CurrentAudioPath = '';
        SimulatedClassNames = {'Gun Shot', 'Siren', 'Engine Idling', 'Dog Bark', 'Ambient Noise'};
        TimerForDateTime % For updating date/time label
        % Placeholder for actual model and feature params
        TrainedModel_Placeholder = true; % True if model is "loaded"
        % FeatureExtractionParams_Placeholder = struct(...);
        PrefGroup = 'AuraShieldSettings'
        DefaultSettings
        ActiveModel % Holds the currently loaded machine learning model
        ActiveModelName = ''; % Name of the loaded model, e.g., 'SVM'
        MFCC
        SVMModel
    end


    properties (Access = public)
        AdditionalSettingsPanel      matlab.ui.container.Panel
        SelectedModel
    end
    
    methods (Access = private)

        % --- ADD THIS ENTIRE NEW FUNCTION ---
        function loadActiveModel(app)
    settings = readSettingsFromUI(app); % Get current UI settings

    modelToLoad = '';
    if strcmp(settings.ModelSelection, 'Select Manually')
        modelToLoad = settings.AvailableModel;
    else
        app.logMessage('Autoselect model not yet implemented. Please select a model manually.');
        app.ActiveModel = []; % Clear any active model
        app.ActiveModelName = 'None';
        return;
    end

    % Prevent reloading the same model if it's already active
    if strcmp(app.ActiveModelName, modelToLoad)
        app.logMessage(['Model ''', modelToLoad, ''' is already loaded.']);
        return;
    end
    app.logMessage(['Attempting to load model: ', modelToLoad]);

    % Assumes the 'models' folder is in the project root relative to where MATLAB is running
    % Corrected path: It's 'models', not 'data' if your folder is named 'models'
    modelPath = fullfile('models');

    try
        switch modelToLoad
            case 'SVM'
                loadedData = load(fullfile(modelPath, 'svm_model.mat'));
                % Check for common variable names inside the .mat file
                if isfield(loadedData, 'svmModel')
                    app.ActiveModel = loadedData.svmModel;
                elseif isfield(loadedData, 'trainedModel')
                    app.ActiveModel = loadedData.trainedModel;
                else
                    error('Could not find a valid model variable in svm_model.mat.');
                end
            case 'CNN'
                loadedData = load(fullfile(modelPath, 'cnn_UrbanSound_MFCC.mat'));
                if isfield(loadedData, 'net') % <-- THIS IS THE EXPECTED VARIABLE NAME
                    app.ActiveModel = loadedData.net; % <-- Load the 'net' variable
                else
                    % If 'net' is not found, throw a clear error message.
                    % This means your CNN training script did not save the 'net' variable
                    % into 'cnn_UrbanSound_MFCC.mat' correctly.
                    error('Expected variable "net" not found in cnn_UrbanSound_MFCC.mat. Please ensure the CNN training script saved the model as "net".');
                end
            case 'KNN'
                loadedData = load(fullfile(modelPath, 'knn_model.mat'));
                % Check for common variable names inside the .mat file
                if isfield(loadedData, 'knnModel')
                    app.ActiveModel = loadedData.knnModel; % <-- FIXED: Should be loadedData.knnModel
                elseif isfield(loadedData, 'trainedModel') % Less common for KNN, but good fallback
                    app.ActiveModel = loadedData.trainedModel;
                else
                    error('Could not find a valid model variable in knn_model.mat.');
                end
            case 'RandomForest'
                loadedData = load(fullfile(modelPath, 'rfModel_MFCC.mat'));
                if isfield(loadedData, 'rfModel')
                    app.ActiveModel = loadedData.rfModel;
                else
                    error('Could not find a valid model variable in rfModel_MFCC.mat.');
                end
            case 'LSTM'
                loadedData = load(fullfile(modelPath, 'lstm_model.mat'));

                % Check for common variable names inside the .mat file
                if isfield(loadedData, 'lstmModel')
                    app.ActiveModel = loadedData.lstmModel;  % Use the model as-is
                elseif isfield(loadedData, 'net')
                    app.ActiveModel = loadedData.net;        % In case it's stored as 'net'
                else
                    error('Could not find a valid model variable in lstm_model.mat.');
                end

            % Add cases for 'CNN-1D', 'CNN-2D', 'RNN/LSTM' here as you implement them

            otherwise
                error(['Model ''', modelToLoad, ''' is not yet supported for loading.']);
        end

        app.ActiveModelName = modelToLoad;
        app.logMessage(['Successfully loaded model: ', app.ActiveModelName]);
        uialert(app.UIFigure, ['Model ''', app.ActiveModelName, ''' is now active.'], 'Model Loaded');

    catch ME
        app.ActiveModel = [];
        app.ActiveModelName = 'None';
        app.logMessage(['Error loading model: ', ME.message]);
        uialert(app.UIFigure, ['Failed to load model ''', modelToLoad, '''. Reason: ', ME.message], 'Model Load Error', 'Icon', 'error');
    end
end
        function plotWaveform(app)
             if isempty(app.AudioData), return; end
             timeVector = (0:length(app.AudioData)-1) / app.Fs;
             plot(app.WaveformAxes, timeVector, app.AudioData);
             xlabel(app.WaveformAxes, 'Time (s)');
             ylabel(app.WaveformAxes, 'Amplitude');
             title(app.WaveformAxes, 'Audio Waveform');
             grid(app.WaveformAxes, 'on');
             axis(app.WaveformAxes, 'tight');
             DefaultSettings = struct('ModelSelection', 'Autoselect best model', 'ThresholdSelection', 'Autoselect (>80%)');
             PrefGroup = 'AuraShieldSettings'; % Group name for saving preferences
        end


        function plotSpectrogram(app)
            if isempty(app.AudioData) || isempty(app.Fs)
                cla(app.SpectrogramAxes);
                title(app.SpectrogramAxes, 'No Audio Data');
                xlabel(app.SpectrogramAxes, 'Time (s)');
                ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
                return;
            end

            try
                % Audio signal
                x = app.AudioData(:); % Ensure column
                fs = app.Fs;

                % Parameters
                winLength = round(0.03 * fs); % 30 ms window
                overlap = round(0.02 * fs);   % 20 ms overlap
                nfft = 1024;

                % Generate spectrogram
                [S, F, T] = spectrogram(x, winLength, overlap, nfft, fs);

                % Convert to decibels
                S_dB = 10 * log10(abs(S) + eps);

                % Plot on UIAxes
                imagesc(app.SpectrogramAxes, T, F, S_dB);
                axis(app.SpectrogramAxes, 'xy');
                xlabel(app.SpectrogramAxes, 'Time (s)');
                ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
                title(app.SpectrogramAxes, 'Spectrogram');
                colorbar(app.SpectrogramAxes);

                % Optional colormap
                try
                    colormap(app.SpectrogramAxes, app.ColormapDropDown.Value);
                catch
                    colormap(app.SpectrogramAxes, 'parula');
                end

            catch ME
                app.logMessage(['Spectrogram plotting error: ', ME.message]);
                cla(app.SpectrogramAxes);
                title(app.SpectrogramAxes, 'Error Generating Spectrogram');
                uialert(app.UIFigure, 'Spectrogram plotting failed.', 'Error');
            end
        end


        function resetDetectionResults(app)
            app.DetectionResultLabel.Text = '---';
            app.DetectionResultLabel.FontColor = [0 0 0]; % Reset to default
            app.ConfidenceGauge.Value = 0;
            app.ThreatIndicatorLamp.Color = [0.5 0.5 0.5]; % Grey
        end

        function audioPlaybackFinished(app)
            app.IsPlaying = false;
            if ~isempty(app.AudioData) % Ensure audio is still loaded
                 app.PlayAudioButton.Enable = 'on';
            end
            app.StopAudioButton.Enable = 'off';
            if isvalid(app.UIFigure) % Check if app is still running
                app.logMessage('Audio playback finished.');
            end
        end

        function ColormapDropDownValueChanged(app, ~)
            if ~isempty(app.AudioData) && ishghandle(app.SpectrogramAxes.Children) % Check if spectrogram is plotted
                colormap(app.SpectrogramAxes, app.ColormapDropDown.Value);
                app.logMessage(['Spectrogram colormap changed to: ', app.ColormapDropDown.Value]);
            end
        end

        function ClearLogButtonPushed(app, ~)
            app.LogTextArea.Value = {sprintf('%s Log Cleared.', datetime("now"))};
        end
    

        function ApplySettingsButtonPushed(app, ~)
            sensitivity = app.SensitivityKnob.Value; % Or slider
            dogBarkEnabled = app.DogBarkCheckBox.Value;
            stealthMode = app.StealthModeCheckBox.Value;
            selectedTheme = app.ThemeDropDown.Value;
            app.logMessage(sprintf('Simulated settings applied: Sensitivity=%s, DogBark=%d, Stealth=%d, Theme=%s', ...
                sensitivity, dogBarkEnabled, stealthMode, selectedTheme));
            uialert(app.UIFigure, 'Settings applied (simulation only). UI theme change requires restart or advanced coding.', 'Settings Applied');
            % Actual theme change would involve iterating through components and setting colors
            % if strcmp(selectedTheme, "Dark Pro")
            %   app.UIFigure.Color = [0.2 0.2 0.2];
            %   % ... and for all other components and text
            % elseif strcmp(selectedTheme, "Light Breeze")
            %   app.UIFigure.Color = [0.94 0.94 0.94]; % Default MATLAB light
            %   % ...
            % end
        end
        function updateUIEnableState(app)
            % --- START OF REPLACEMENT CODE ---

            % Get the text from the selected button in the Model Selection group
            modelSelectionButton = app.ButtonGroup.SelectedObject;
            modelSelectionText = modelSelectionButton.Text;
            if iscell(modelSelectionText)
                modelSelectionText = modelSelectionText{1};
            end
            
            % Enable/disable the manual model selection based on the text
            app.ButtonGroup_2.Enable = strcmp(modelSelectionText, 'Select Manually');

            % Get the text from the selected button in the Threshold Accuracy group
            thresholdSelectionButton = app.ButtonGroup_3.SelectedObject;
            thresholdSelectionText = thresholdSelectionButton.Text;
            if iscell(thresholdSelectionText)
                thresholdSelectionText = thresholdSelectionText{1};
            end
            
            % Enable/disable the threshold spinner based on the text
            app.ThresholdValueSpinner.Enable = strcmp(thresholdSelectionText, 'Select Manually');
            % --- END OF REPLACEMENT CODE ---
        end


function applySettingsToUI(app, settings)
            % --- START OF REPLACEMENT CODE ---

            % Helper function to find and select the correct radio button
            function setSelectedButton(buttonGroup, buttonText)
                % Iterate through the children of the button group
                for i = 1:numel(buttonGroup.Children)
                    childComponent = buttonGroup.Children(i);
                    
                    % --- ADDED CHECK ---
                    % Only process components that are radio buttons.
                    % This will skip spinners, labels, etc.
                    if ~isa(childComponent, 'matlab.ui.control.RadioButton')
                        continue; % Skip to the next child
                    end
                    
                    childText = childComponent.Text; % Get the text property
                    
                    % Check if the text is a cell array (for multi-line text)
                    if iscell(childText)
                        childText = childText{1};
                    end
                    
                    % Compare the button's text with the target text
                    if strcmp(childText, buttonText)
                        % If it matches, set this as the selected object and exit
                        buttonGroup.SelectedObject = childComponent;
                        return;
                    end
                end
            end

            % Apply settings using the robust helper function
            setSelectedButton(app.ButtonGroup, settings.ModelSelection);
            setSelectedButton(app.ButtonGroup_3, settings.ThresholdSelection);

            if isfield(settings, 'AvailableModel') && strcmp(settings.ModelSelection, 'Select Manually')
                setSelectedButton(app.ButtonGroup_2, settings.AvailableModel);
            end
            if isfield(settings, 'ThresholdValue') && strcmp(settings.ThresholdSelection, 'Select Manually')
                app.ThresholdValueSpinner.Value = settings.ThresholdValue;
            end

            updateUIEnableState(app);
            % --- END OF REPLACEMENT CODE ---
        end


function settings = readSettingsFromUI(app)
    settings = struct();
    settings.ModelSelection = app.ButtonGroup.SelectedObject.Text;
    settings.ThresholdSelection = app.ButtonGroup_3.SelectedObject.Text;
    
    % Only capture manual selections if they are active
    if strcmp(settings.ModelSelection, 'Select Manually')
        settings.AvailableModel = app.ButtonGroup_2.SelectedObject.Text;
    end
    if strcmp(settings.ThresholdSelection, 'Select Manually')
        settings.ThresholdValue = app.ThresholdValueSpinner.Value;
    end
end


function saveSettings(app)
    % Gathers settings from UI and saves them using setpref.
    currentSettings = readSettingsFromUI(app);
    fields = fieldnames(currentSettings);
    for i = 1:length(fields)
        fieldName = fields{i};
        % setpref saves preferences between MATLAB sessions
        setpref(app.PrefGroup, fieldName, currentSettings.(fieldName));
    end
    disp('Settings saved.');
    % Optional: Show a dialog to the user
    uialert(app.UIFigure, 'Your settings have been saved.', 'Settings Saved');
end

function loadSettings(app)
    % Loads settings using getpref. If a preference doesn't exist,
    % it uses the value from DefaultSettings.
    
    % Check if the preference group exists
    if ispref(app.PrefGroup)
        disp('Loading saved settings.');
        loadedSettings = struct();
        fields = fieldnames(app.DefaultSettings);
        for i = 1:length(fields)
            fieldName = fields{i};
            % getpref(group, name, defaultValue) is very convenient
            loadedSettings.(fieldName) = getpref(app.PrefGroup, fieldName, app.DefaultSettings.(fieldName));
        end
        applySettingsToUI(app, loadedSettings);
    else
        % If no preferences are saved, apply the hard-coded defaults
        disp('No saved settings found. Applying default settings.');
        applySettingsToUI(app, app.DefaultSettings);
    end
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % --- THIS IS THE NEW CODE SECTION ---
            % This function runs once when the app starts.
            
            % Set the initial state of the UI components.
            % By default, 'Autoselect' is chosen for both panels,
            % so we disable the manual selection areas.
            loadActiveModel(app); % Load the model based on the loaded settings
            % Disable the "Available Models" radio button group initially.
            app.ButtonGroup_2.Enable = 'off';
            
            % Disable the "Threshold Value" spinner initially.
            app.ThresholdValueSpinner.Enable = 'off';

            app.DefaultSettings = struct( ...
    'ModelSelection', 'Autoselect best model', ...
    'ThresholdSelection', 'Autoselect (>80%)' ...
);


            loadSettings(app); % Load saved settings or apply defaults
            updateUIEnableState(app); % Update enabled/disabled states
            
            disp('App started. Manual selection panels are disabled.');
            app.SystemStatusLamp.Color = [0 1 0]; % Green - Ready
            app.TitleLabel.Text = "AuraShield - Sound Intelligence";
            app.TitleLabel.FontWeight = 'bold';
            app.TitleLabel.FontSize = 18;
            app.RecordDurationSpinner.Value = 5; % Default 5s
            app.AnalyzeSoundButton.Enable = 'off';
            app.PlayAudioButton.Enable = 'off';
            app.StopAudioButton.Enable = 'off';
            app.ConfidenceGauge.Value = 0;
            app.ThreatIndicatorLamp.Color = [0.5 0.5 0.5]; % Grey
            app.DetectionResultLabel.Text = '---';
            app.DetectionResultLabel.FontColor = [0 0 0]; % Black for light theme default
            app.ColormapDropDown.Items = {'parula', 'jet', 'hot', 'cool', 'gray', 'pink'};
            app.ColormapDropDown.Value = 'parula';


            % Start DateTime Timer (Optional)
            app.TimerForDateTime = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ... % Update every second
                'TimerFcn', @(~,~) app.updateDateTimeLabel());
            start(app.TimerForDateTime);
            % Initial plot styling
            title(app.WaveformAxes, 'Load or Record Audio');
            xlabel(app.WaveformAxes, 'Time (s)'); ylabel(app.WaveformAxes, 'Amplitude');
            grid(app.WaveformAxes, 'on');
            title(app.SpectrogramAxes, 'Audio Spectrogram');
            xlabel(app.SpectrogramAxes, 'Time (s)'); ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
            colorbar(app.SpectrogramAxes, 'off'); % Initially off
            app.logMessage('GUI startup complete. Waiting for audio input.');
        end
        function updateDateTimeLabel(app)
            if isvalid(app.UIFigure) % Check if app is still running
                 app.DateTimeLabel.Text = string(datetime("now"), 'dd-MMM-yyyy HH:mm:ss');
            end
        end
        function logMessage(app, message)
            currentDatetime = datetime("now");
            timestampStr = string(currentDatetime, 'HH:mm:ss');
            newLogEntry = sprintf('[%s] %s', timestampStr, message);
            fprintf('%s\n', newLogEntry);
            currentLog = app.LogTextArea.Value;
            if isempty(currentLog) || (isstring(currentLog) && currentLog == "") || (ischar(currentLog) && all(currentLog == ' '))
                app.LogTextArea.Value = newLogEntry;
            else
                if ischar(currentLog)
                    currentLog = {currentLog};
                end
                app.LogTextArea.Value = [currentLog; newLogEntry];
            end
            if numel(app.LogTextArea.Value) > 200
                app.LogTextArea.Value = app.LogTextArea.Value(end-199:end);
            end
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            
    
    [fileName, filePath] = uigetfile({'*.wav;*.mp3;*.m4a', 'Audio Files (*.wav, *.mp3, *.m4a)'}, 'Select Audio File');
    if fileName ~= 0
        app.CurrentAudioPath = fullfile(filePath, fileName);
        app.FileNameLabel.Text = fileName;
        app.logMessage(['Loading audio: ', app.CurrentAudioPath]);
        app.SystemStatusLamp.Color = [1 0.8 0]; % Yellow - Processing
        drawnow;
        
        try
        %% 🔹 SVM Evaluation
        % Load the SVM model
        modelData = load('models/svm_model.mat', 'svmModel');
        svmModel = modelData.svmModel;
        app.logMessage("✅ Loaded SVM model for evaluation.");

        % Load test features
        data = load('src/feature_extraction/features_mfcc_spec.mat', 'testFeatures');
        testFeatures = data.testFeatures;

        % Convert MFCC matrices to mean vectors
        mfccToTable = @(featureStruct) ...
            struct2table(arrayfun(@(x) struct( ...
                'Features', mean(x.mfcc, 2)', ...
                'Label', x.label), ...
                testFeatures));

        testTbl = mfccToTable(testFeatures);
        X_test = vertcat(testTbl.Features);
        Y_test = categorical(testTbl.Label);

        % Predict and calculate accuracy for SVM
        Y_pred_svm = predict(svmModel, X_test);
        accuracy_svm = sum(Y_pred_svm == Y_test) / numel(Y_test);
        accPercent_svm = accuracy_svm * 100;

        % Update label and gauge for SVM
        app.SVMAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_svm);
        app.SVMAccuracyGauge.Value = accPercent_svm;
        app.logMessage("📊 SVM evaluation complete and displayed.");

        %% 🔸 Random Forest Evaluation
        rfData = load('models/rfModel_MFCC.mat', 'rfModel');
        rfModel = rfData.rfModel;
        app.logMessage("✅ Loaded Random Forest model for evaluation.");

        % Predict and calculate accuracy for Random Forest
        Y_pred_rf = predict(rfModel, X_test);
        Y_pred_rf = categorical(Y_pred_rf); % convert from cell array
        accuracy_rf = sum(Y_pred_rf == Y_test) / numel(Y_test);
        accPercent_rf = accuracy_rf * 100;

        % Update label and gauge for Random Forest
        app.RandomForestAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_rf);
        app.RandomForestAccuracyGauge.Value = accPercent_rf;
        app.logMessage("📊 Random Forest evaluation complete and displayed.");

    catch ME
        app.logMessage(['❌ Evaluation error: ', ME.message]);
        % Reset UI elements on failure
        app.SVMAccuracyLabel.Text = "Accuracy: Error";
        app.SVMAccuracyGauge.Value = 0;
        app.RandomForestAccuracyLabel.Text = "Accuracy: Error";
        app.RandomForestAccuracyGauge.Value = 0;
        end
        
        try
            % Read audio
            [audioData, fs] = audioread(app.CurrentAudioPath);
            if size(audioData, 2) > 1
                audioData = mean(audioData, 2); % Convert stereo to mono
            end
            duration = length(audioData) / fs;
            app.AudioInfoLabel.Text = sprintf('Duration: %.2fs\nFs: %d Hz\nChannels: 1', ...
                                              duration, fs);

            % --------------------------
            % 🎯 Add Preprocessing Here
            % --------------------------

            % 1. Normalize
            audioData = audioData / max(abs(audioData));

            % 2. Optional: Noise Reduction
            audioData = medfilt1(audioData, 3);  % Median filter

            % 3. Optional: MFCC Extraction
            mfccFeatures = mfcc(audioData, fs);  % Built-in MATLAB function

            % 4. Store Preprocessed Data in App Properties
            app.AudioData = audioData;
            app.Fs = fs;
            app.MFCC = mfccFeatures;

            % 5. Log
            app.logMessage("📌 Input data successfully processed and features are ready for analysis.");;

            % Plotting
            plotWaveform(app);
            plotSpectrogram(app);

            app.AnalyzeSoundButton.Enable = 'on';
            app.PlayAudioButton.Enable = 'on';
            app.SystemStatusLamp.Color = [0 1 0]; % Green
            app.logMessage('Audio loaded and ready for analysis.');
            resetDetectionResults(app);

        catch ME
            app.SystemStatusLamp.Color = [1 0 0]; % Red - Error
            app.logMessage(['Error loading audio: ', ME.message]);
            uialert(app.UIFigure, ['Could not load audio: ' ME.message], 'Load Error');
            app.FileNameLabel.Text = 'Load Error. Try Again.';
        end 
        if size(audioData, 2) > 1
    audioData = mean(audioData, 2);
        end

app.AudioData = audioData;
app.Fs = fs;
app.MFCC = mfcc(audioData, fs);  % Now safe for MFCC extraction
    end 
    

    try
    % 1. Check if audio file is selected
    if isempty(app.CurrentAudioPath) || ~isfile(app.CurrentAudioPath)
        uialert(app.UIFigure, 'Please load an audio file first.', 'Missing Audio');
        return;
    end

    % 2. Read audio from file
    [audioData, fs] = audioread(app.CurrentAudioPath);

    % 3. Convert to mono if stereo
    if size(audioData, 2) > 1
        audioData = mean(audioData, 2);
    end

    % 4. Save to app properties
    app.AudioData = audioData;
    app.Fs = fs;

    % 5. Extract MFCC
    mfccFeatures = mfcc(audioData, fs, 'NumCoeffs', 13, 'LogEnergy', 'Replace');

    % 6. Check shape and fix if needed
    if isempty(mfccFeatures)
        uialert(app.UIFigure, 'MFCC extraction failed. Please use a valid audio file.', 'MFCC Error');
        return;
    end

    % Force MFCC to be 13×T (13 rows, time steps as columns)
    [rows, cols] = size(mfccFeatures);
    if rows == 13
        % Already correct
    elseif cols == 13
        mfccFeatures = mfccFeatures';  % Transpose
    else
        uialert(app.UIFigure, sprintf("MFCC format unexpected: %dx%d. Need 13 MFCCs.", rows, cols), 'MFCC Error');
        return;
    end

    % 7. Save MFCC
    app.MFCC = mfccFeatures;

    % 8. Create 1×13 feature vector
    inputFeature = mean(mfccFeatures, 2)';  % [1 x 13]

    % 9. Load Random Forest model
    modelData = load('models/rfModel_MFCC.mat', 'rfModel');
    rfModel = modelData.rfModel;

    % 10. Predict
    Y_pred = predict(rfModel, inputFeature);  % returns cell
    prediction = string(Y_pred{1});

    % 11. Display result
    app.DetectionResultLabel.Text = sprintf(" %s", prediction);
    app.logMessage("✅ Prediction from loaded audio complete.");

catch ME
    app.DetectionResultLabel.Text = "Prediction Error";
    app.logMessage(['❌ Prediction from loaded audio failed: ', ME.message]);
    end


        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)


    duration = app.RecordDurationSpinner.Value;
    app.FileNameLabel.Text = sprintf('Recording for %ds...', duration);
    app.logMessage(sprintf('Starting %.1fs recording...', duration));
    app.SystemStatusLamp.Color = [1 0.5 0]; % Orange - Recording

    % Disable buttons during recording
    app.LoadAudioButton.Enable = 'off';
    app.RecordAudioButton.Enable = 'off';
    app.AnalyzeSoundButton.Enable = 'off';
    drawnow;
    try
        %% 🔹 SVM Evaluation
        % Load the SVM model
        modelData = load('models/svm_model.mat', 'svmModel');
        svmModel = modelData.svmModel;
        app.logMessage("✅ Loaded SVM model for evaluation.");

        % Load test features
        data = load('src/feature_extraction/features_mfcc_spec.mat', 'testFeatures');
        testFeatures = data.testFeatures;

        % Convert MFCC matrices to mean vectors
        mfccToTable = @(featureStruct) ...
            struct2table(arrayfun(@(x) struct( ...
                'Features', mean(x.mfcc, 2)', ...
                'Label', x.label), ...
                testFeatures));

        testTbl = mfccToTable(testFeatures);
        X_test = vertcat(testTbl.Features);
        Y_test = categorical(testTbl.Label);

        % Predict and calculate accuracy for SVM
        Y_pred_svm = predict(svmModel, X_test);
        accuracy_svm = sum(Y_pred_svm == Y_test) / numel(Y_test);
        accPercent_svm = accuracy_svm * 100;

        % Update label and gauge for SVM
        app.SVMAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_svm);
        app.SVMAccuracyGauge.Value = accPercent_svm;
        app.logMessage("📊 SVM evaluation complete and displayed.");

        %% 🔸 Random Forest Evaluation
        rfData = load('models/rfModel_MFCC.mat', 'rfModel');
        rfModel = rfData.rfModel;
        app.logMessage("✅ Loaded Random Forest model for evaluation.");

        % Predict and calculate accuracy for Random Forest
        Y_pred_rf = predict(rfModel, X_test);
        Y_pred_rf = categorical(Y_pred_rf); % convert from cell array
        accuracy_rf = sum(Y_pred_rf == Y_test) / numel(Y_test);
        accPercent_rf = accuracy_rf * 100;

        % Update label and gauge for Random Forest
        app.RandomForestAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_rf);
        app.RandomForestAccuracyGauge.Value = accPercent_rf;
        app.logMessage("📊 Random Forest evaluation complete and displayed.");

    catch ME
        app.logMessage(['❌ Evaluation error: ', ME.message]);
        % Reset UI elements on failure
        app.SVMAccuracyLabel.Text = "Accuracy: Error";
        app.SVMAccuracyGauge.Value = 0;
        app.RandomForestAccuracyLabel.Text = "Accuracy: Error";
        app.RandomForestAccuracyGauge.Value = 0;
    end


    try
        recorder = audiorecorder(44100, 16, 1); % Standard Fs, bit depth, channels
        recordblocking(recorder, duration);     % Synchronous recording
        audioData = getaudiodata(recorder);
        fs = recorder.SampleRate;

        % --------------------------
        % 🎯 Add Preprocessing Here
        % --------------------------

        % 1. Normalize
        audioData = audioData / max(abs(audioData));

        % 2. Optional: Noise Reduction
        audioData = medfilt1(audioData, 3);  % Median filter

        % 3. Optional: MFCC Extraction
        mfccFeatures = mfcc(audioData, fs);  % Built-in MATLAB function

        % 4. Store Preprocessed Data
        app.AudioData = audioData;
        app.Fs = fs;
        app.MFCC = mfccFeatures;
        app.CurrentAudioPath = ''; % No file path for recorded audio

        % 5. Update GUI and Log
        app.FileNameLabel.Text = sprintf('Recorded Audio (%.1fs)', duration);
        duration_rec = length(app.AudioData) / app.Fs;
        app.AudioInfoLabel.Text = sprintf('Duration: %.2fs\nFs: %d Hz\nChannels: %d', ...
                                          duration_rec, app.Fs, size(app.AudioData,2));

        plotWaveform(app);
        plotSpectrogram(app);

        app.AnalyzeSoundButton.Enable = 'on';
        app.PlayAudioButton.Enable = 'on';
        app.SystemStatusLamp.Color = [0 1 0]; % Green
        app.logMessage("✔ Recording complete and input data successfully preprocessed.");
        app.logMessage("📌 MFCCs extracted and ready for analysis.");
        resetDetectionResults(app);

    catch ME
        app.SystemStatusLamp.Color = [1 0 0]; % Red
        app.logMessage(['Error during recording: ', ME.message]);
        uialert(app.UIFigure, ['Recording failed: ' ME.message], 'Recording Error');
        app.FileNameLabel.Text = 'Recording Failed.';
    end
    
if size(audioData, 2) > 1
    audioData = mean(audioData, 2);
end

app.AudioData = audioData;
app.Fs = fs;
app.MFCC = mfcc(audioData, fs);  % Now safe for MFCC extraction

    % Re-enable buttons
    app.LoadAudioButton.Enable = 'on';
    app.RecordAudioButton.Enable = 'on';
    try
        % Check for valid MFCC input
        if isempty(app.MFCC)
            uialert(app.UIFigure, 'Please load or record an audio before detection.', 'Input Missing');
            return;
        end

        % Extract mean of MFCC to create 1x13 feature vector
        inputFeature = mean(app.MFCC, 2)';  % [1 x 13]

        % Load trained Random Forest model
        modelData = load('models/rfModel_MFCC.mat', 'rfModel');
        rfModel = modelData.rfModel;

        % Predict class
        Y_pred = predict(rfModel, inputFeature);  % returns cell
        prediction = string(Y_pred{1});           % Convert to string

        % Display prediction in DetectionResultLabel
        app.DetectionResultLabel.Text = sprintf("Predicted Class: %s", prediction);

        % Log result
        app.logMessage("✅ Prediction using Random Forest complete.");

    catch ME
        app.DetectionResultLabel.Text = "Prediction Error";
        app.logMessage(['❌ Prediction failed: ', ME.message]);
    end


        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            if ~isempty(app.AudioData) && ~app.IsPlaying
                app.AudioPlayer = audioplayer(app.AudioData, app.Fs);
                app.AudioPlayer.StopFcn = @(~,~) audioPlaybackFinished(app);
                play(app.AudioPlayer);
                app.IsPlaying = true;
                app.PlayAudioButton.Enable = 'off';
                app.StopAudioButton.Enable = 'on';
                app.logMessage('Audio playback started.');
            end
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            if app.IsPlaying && ~isempty(app.AudioPlayer)
                stop(app.AudioPlayer);
                % StopFcn will handle resetting IsPlaying and button states
            end
        end

        % Button pushed function: AnalyzeSoundButton
        function AnalyzeSoundButtonPushed(app, event)
            % --- START OF REPLACEMENT CODE ---
        if isempty(app.AudioData)
            uialert(app.UIFigure, 'No audio data to analyze.', 'Analysis Error');
            return;
        end
        
        if isempty(app.ActiveModel)
             uialert(app.UIFigure, 'No AI Model is active. Please select and save a model in the Settings tab.', 'Model Error');
            return;
        end
        
        app.logMessage(['Analyzing sound with model: ', app.ActiveModelName]);
        app.SystemStatusLamp.Color = [1 0.8 0]; % Yellow - Processing 
        app.AnalyzeSoundButton.Text = 'ANALYZING...'; 
        app.AnalyzeSoundButton.Enable = 'off'; 
        drawnow; 
        
        try
            % Step 1: Extract features.
            % IMPORTANT: This assumes you have a function named 'extractAudioFeatures'
            % in your 'src/feature_extraction' folder, and that folder is on the MATLAB path.
            features = extractAudioFeatures(app.AudioData, app.Fs); 
            
            % Step 2: Predict using the active model.
            [predictedLabel, scores] = predict(app.ActiveModel, features);
            
            % Step 3: Determine confidence from the prediction scores.
            confidenceScore = max(scores);
            
            % Step 4: Update the GUI with the real results.
            app.DetectionResultLabel.Text = char(predictedLabel);
            app.ConfidenceGauge.Value = confidenceScore * 100;
            
            % Update lamp and label colors based on the predicted threat.
            switch lower(char(predictedLabel))
                case {'gun shot', 'siren'}
                    app.ThreatIndicatorLamp.Color = [1, 0, 0]; % Red 
                    app.DetectionResultLabel.FontColor = [1, 0, 0];
                case 'engine idling'
                    app.ThreatIndicatorLamp.Color = [1, 1, 0]; % Yellow 
                    app.DetectionResultLabel.FontColor = [0.6, 0.6, 0];
                case 'dog bark'
                     app.ThreatIndicatorLamp.Color = [0, 0.7, 0.7]; % Teal 
                    app.DetectionResultLabel.FontColor = [0, 0.5, 0.5]; 
                otherwise % Ambient Noise
                    app.ThreatIndicatorLamp.Color = [0, 1, 0]; % Green 
                    app.DetectionResultLabel.FontColor = [0, 0.5, 0]; 
            end
            
            app.logMessage(sprintf('Detection: %s (Confidence: %.1f%%)', char(predictedLabel), confidenceScore*100));
            
        catch ME
            app.logMessage(['Analysis Error: ', ME.message]);
            uialert(app.UIFigure, ['An error occurred during analysis: ' ME.message], 'Analysis Failed', 'Icon', 'error');
            resetDetectionResults(app);
        end
        
        % Re-enable UI elements after analysis is complete.
        app.SystemStatusLamp.Color = [0 1 0]; % Green 
        app.AnalyzeSoundButton.Text = 'ANALYZE SOUND';
        app.AnalyzeSoundButton.Enable = 'on'; 
        % --- END OF REPLACEMENT CODE ---
        end

        % Button pushed function: SaveSnapshotButton
        function SaveSnapshotButtonPushed(app, event)
            if isempty(app.AudioData)
                 app.logMessage('Nothing to save. Load or record audio first.');
                 return;
            end

            app.logMessage('Initiating snapshot save...');
            
            % Open a dialog to choose save location and file name
            [fileName, filePath, ~] = uiputfile(...
                {'*.png', 'PNG Image (*.png)'; ...
                 '*.jpg', 'JPEG Image (*.jpg)'; ...
                 '*.pdf', 'PDF Document (*.pdf)'}, ...
                'Save Spectrogram Snapshot', ...
                'AuraShield_Spectrogram.png'); % Default file name

            if fileName ~= 0 % If user didn't cancel
                fullFilePath = fullfile(filePath, fileName);
                
                try
                    % Save the SpectrogramAxes
                    exportgraphics(app.SpectrogramAxes, fullFilePath, 'Resolution', 300); % 300 DPI for good quality

                    app.logMessage(['Spectrogram snapshot saved to: ', fullFilePath]);

                    % Optionally, you could also save the waveform
                    % prompt = 'Do you also want to save the Waveform?';
                    % title = 'Save Waveform?';
                    % selection = uiconfirm(app.UIFigure, prompt, title, 'Options', {'Yes','No'}, 'DefaultOption', 1);
                    % if strcmp(selection,'Yes')
                    %     [wfFileName, wfFilePath] = uiputfile('AuraShield_Waveform.png', 'Save Waveform Snapshot');
                    %     if wfFileName ~= 0
                    %         exportgraphics(app.WaveformAxes, fullfile(wfFilePath, wfFileName), 'Resolution', 300);
                    %         app.logMessage(['Waveform snapshot saved to: ', fullfile(wfFilePath, wfFileName)]);
                    %     end
                    % end

                catch ME
                    app.logMessage(['Error saving snapshot: ', ME.message]);
                    uialert(app.UIFigure, ['Could not save snapshot: ' ME.message], 'Save Error', 'Icon', 'error');
                end
            else
                app.logMessage('Snapshot save cancelled.');
            end


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.WaveformAxes); title(app.WaveformAxes, 'Load or Record Audio');
            cla(app.SpectrogramAxes); title(app.SpectrogramAxes, 'Audio Spectrogram'); colorbar(app.SpectrogramAxes, 'off');
            app.AudioData = []; app.Fs = [];
            app.FileNameLabel.Text = 'No file loaded';
            app.AudioInfoLabel.Text = 'Duration: --s, Fs: --Hz, Channels: --';
            resetDetectionResults(app);
            app.AnalyzeSoundButton.Enable = 'off';
            app.PlayAudioButton.Enable = 'off';
            app.StopAudioButton.Enable = 'off';
            if app.IsPlaying && ~isempty(app.AudioPlayer), stop(app.AudioPlayer); end
            app.logMessage('Analysis cleared.');
        end

        % Button down function: WaveformAxes
        function WaveformAxesButtonDown(app, event)
            if isempty(app.AudioData), return; end
            timeVector = (0:length(app.AudioData)-1) / app.Fs;
            plot(app.WaveformAxes, timeVector, app.AudioData);
            xlabel(app.WaveformAxes, 'Time (s)');
            ylabel(app.WaveformAxes, 'Amplitude');
            title(app.WaveformAxes, 'Audio Waveform');
            grid(app.WaveformAxes, 'on');
            axis(app.WaveformAxes, 'tight');
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            % Check which radio button was selected
            if strcmp(selectedButton.Text, 'Select Manually')
                % If 'Select Manually' is chosen, enable the model selection group.
                app.ButtonGroup_2.Enable = 'on';
            else % This handles the 'Autoselect best model' case
                % Otherwise, disable the model selection group.
                app.ButtonGroup_2.Enable = 'off';
            end       
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            % This function runs when the user selects a specific model.
            selectedModelButton = app.ButtonGroup_2.SelectedObject;
            selectedModel = selectedModelButton.Text;
            
            % Store the selected model and update a log (optional)
            app.SelectedModel = selectedModel;
            % app.LogTextArea.Value = ['Selected model: ' selectedModel];
            fprintf('Selected model from ButtonGroup_2: %s\n', selectedModel);
  
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            
            % Check which radio button was selected
            if strcmp(selectedButton.Text, 'Select Manually')
                % If 'Select Manually' is chosen, enable the spinner.
                app.ThresholdValueSpinner.Enable = 'on';
            else % This handles the 'Autoselect (> 80%)' case
                % Otherwise, disable the spinner.
                app.ThresholdValueSpinner.Enable = 'off';
            end
        end

        % Button pushed function: ResettoDefaultsButton
        function ResettoDefaultsButtonPushed(app, event)
            % Callback for the 'Reset to Defaults' button.
            disp('Resetting settings to default.');
            applySettingsToUI(app, app.DefaultSettings);
            % Optional: Show a confirmation dialog
            uialert(app.UIFigure, 'All settings have been reset to their default values.', 'Settings Reset');
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            % Callback for the 'Save Settings' button.
            saveSettings(app);
            loadActiveModel(app);   % Loads the actual model based on the UI selection
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged2(app, event)
             % Only replot if audio exists
            if ~isempty(app.AudioData) && ~isempty(app.Fs)
                plotSpectrogram(app);
            end
        end

        % Button pushed function: LearnMoreAboutTheProjectButton
        function LearnMoreAboutTheProjectButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AboutProject;
        end

        % Button pushed function: ClearLogButton
        function ClearLogButtonPushed2(app, event)
        
    % Clear the log display
    app.LogTextArea.Value = {''};  % Clears the log area properly

        end

        % Button pushed function: ExportLogButton
        function ExportLogButtonPushed2(app, event)
% --- Callback function for the 'Export Log' button

    % This function will be executed when the 'Export Log' button is clicked.

    % 1. Get the current log content from your UI component.
    %    Using app.LogTextArea.Value as indicated by your logMessage function.
    if isprop(app, 'LogTextArea') && isvalid(app.LogTextArea)
        logContent = app.LogTextArea.Value;
        % Convert cell array of strings to a single string with newlines if necessary,
        % as uitextarea.Value can return a cell array when WordWrap is on.
        if iscell(logContent)
            logContent = strjoin(logContent, newline);
        end
    else
        % Handle cases where the text area might not be found or is invalid.
        uialert(app.UIFigure, 'Event Log text area (app.LogTextArea) not found or invalid.', 'Error', 'Icon', 'error');
        % For demonstration, let's use a placeholder if the text area isn't found.
        logContent = 'No event log content found. This is placeholder text.';
    end

    % Check if the log content is empty.
    if isempty(logContent) || (isstring(logContent) && logContent == "") || (ischar(logContent) && all(logContent == ' '))
        uialert(app.UIFigure, 'The event log is empty. Nothing to export.', 'Information', 'Icon', 'info');
        return; % Exit the function if there's no content
    end

    % 2. Open a "Save File" dialog to let the user choose the file name and location.
    %    The default extension is .txt.
    [fileName, pathName] = uiputfile('*.txt', 'Save Event Log as Text File', 'EventLog.txt');

    % Check if the user cancelled the save operation.
    if isequal(fileName, 0) || isequal(pathName, 0)
        uialert(app.UIFigure, 'Export operation cancelled.', 'Information', 'Icon', 'info');
        return; % User cancelled, so exit the function
    end

    % Construct the full file path.
    fullPath = fullfile(pathName, fileName);

    % 3. Write the log content directly to the text file.
    try
        fid = fopen(fullPath, 'wt'); % Open file for writing text
        if fid == -1
            error('Could not open file for writing.');
        end
        fprintf(fid, '%s', logContent); % Write the log content
        fclose(fid); % Close the file
        uialert(app.UIFigure, ['Event log successfully exported to:', newline, fullPath], 'Export Complete', 'Icon', 'success');
    catch ME
        uialert(app.UIFigure, ['Error exporting log: ', ME.message], 'Export Error', 'Icon', 'error');
        if fid ~= -1
            fclose(fid); % Ensure file is closed even on error
        end
    end
   

        end

        % Callback function
        function AccuracyTextAreaValueChanged(app, event)
% Button callback to show accuracy in the same TextArea used for input

        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
     
    try
    %% 🔹 SVM Evaluation
    modelData = load('models/svm_model.mat', 'svmModel');
    svmModel = modelData.svmModel;
    app.logMessage("✅ Loaded SVM model for evaluation.");

    data = load('src/feature_extraction/features_mfcc_spec.mat', 'testFeatures');
    testFeatures = data.testFeatures;

    mfccToTable = @(featureStruct) ...
        struct2table(arrayfun(@(x) struct( ...
            'Features', mean(x.mfcc, 2)', ...
            'Label', x.label), ...
            featureStruct));

    testTbl = mfccToTable(testFeatures);
    X_test = vertcat(testTbl.Features);
    Y_test = categorical(testTbl.Label);

    Y_pred_svm = predict(svmModel, X_test);
    accuracy_svm = sum(Y_pred_svm == Y_test) / numel(Y_test);
    accPercent_svm = accuracy_svm * 100;

    app.SVMAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_svm);
    app.SVMAccuracyGauge.Value = accPercent_svm;
    app.logMessage("📊 SVM evaluation complete and displayed.");

    %% 🔸 Random Forest Evaluation
    rfData = load('models/rfModel_MFCC.mat', 'rfModel');
    rfModel = rfData.rfModel;
    app.logMessage("✅ Loaded Random Forest model for evaluation.");

    Y_pred_rf = predict(rfModel, X_test);
    Y_pred_rf = categorical(Y_pred_rf);
    accuracy_rf = sum(Y_pred_rf == Y_test) / numel(Y_test);
    accPercent_rf = accuracy_rf * 100;

    app.RandomForestAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_rf);
    app.RandomForestAccuracyGauge.Value = accPercent_rf;
    app.logMessage("📊 Random Forest evaluation complete and displayed.");

    %% 🔵 KNN Evaluation
    knnData = load('models/knn_model.mat', 'knnModel');
    knnModel = knnData.knnModel;
    app.logMessage("✅ Loaded KNN model for evaluation.");

    Y_pred_knn = predict(knnModel, X_test);
    Y_pred_knn = categorical(Y_pred_knn);
    accuracy_knn = sum(Y_pred_knn == Y_test) / numel(Y_test);
    accPercent_knn = accuracy_knn * 100;

    app.KNNAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", accPercent_knn);
    app.KNNAccuracyGauge.Value = accPercent_knn;
    app.logMessage("📊 KNN evaluation complete and displayed.");

catch ME
    app.logMessage(['❌ Evaluation error: ', ME.message]);

    % Reset UI elements on failure
    app.SVMAccuracyLabel.Text = "Accuracy: Error";
    app.SVMAccuracyGauge.Value = 0;

    app.RandomForestAccuracyLabel.Text = "Accuracy: Error";
    app.RandomForestAccuracyGauge.Value = 0;

    app.KNNAccuracyLabel.Text = "Accuracy: Error";
    app.KNNAccuracyGauge.Value = 0;
    end


    try
    % Load CNN model trained on MFCC
    % ⭐ CHANGE 'accuracy' to 'acc' here:
    cnnData = load('models/cnn_UrbanSound_MFCC.mat', 'net', 'acc'); 
    
    cnnModel = cnnData.net;
    % ⭐ CHANGE 'cnnData.accuracy' to 'cnnData.acc' here:
    cnnData = load('models/cnn_UrbanSound_MFCC.mat', 'net', 'acc'); 
    cnnModel = cnnData.net;
    cnnAccuracy = cnnData.acc * 100;


    % Update label and gauge in GUI
    app.CNNAccuracyLabel.Text = sprintf("Accuracy: %.2f%%", cnnAccuracy);
    app.CNNAccuracyGauge.Value = cnnAccuracy;

    % Log message
    app.logMessage("📊 CNN (MFCC) evaluation complete and displayed.");

catch ME
    app.CNNAccuracyLabel.Text = "Accuracy: Error";
    app.CNNAccuracyGauge.Value = 0;
    app.logMessage(['❌ CNN evaluation failed: ', ME.message]);
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.HeaderPanel = uipanel(app.MainGridLayout);
            app.GridLayout2 = uigridlayout(app.HeaderPanel);
            app.LogoImage = uiimage(app.GridLayout2);
            app.LampLabel = uilabel(app.GridLayout2);
            app.LampLabel.Text = 'Lamp';
            app.SystemStatusLamp = uilamp(app.GridLayout2);
            app.TitleLabel = uilabel(app.GridLayout2);
            app.TitleLabel.Text = 'AuraShield- Sound Intrusion System';
            app.DateTimeLabel = uilabel(app.GridLayout2);
            app.DateTimeLabel.Text = 'dd-mmm-yyyy HH:MM:SS';
            app.MainTabGroup = uitabgroup(app.MainGridLayout);
            app.DetectorTab = uitab(app.MainTabGroup);
            app.DetectorTab.Title = 'Detector';
            app.GridLayout3 = uigridlayout(app.DetectorTab);
            app.AudioControlPanel = uipanel(app.GridLayout3);
            app.AudioControlPanel.Title = 'Audio Control';
            app.GridLayout4 = uigridlayout(app.AudioControlPanel);
            app.WaveformAxes = uiaxes(app.GridLayout4);
            title(app.WaveformAxes, 'Title')
            xlabel(app.WaveformAxes, 'X')
            ylabel(app.WaveformAxes, 'Y')
            zlabel(app.WaveformAxes, 'Z')
            app.WaveformAxes.ButtonDownFcn = createCallbackFcn(app, @WaveformAxesButtonDown, true);
            app.LoadAudioButton = uibutton(app.GridLayout4, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio File';
            app.FileNameLabel = uilabel(app.GridLayout4);
            app.FileNameLabel.Text = 'No file loaded';
            app.ORLabel = uilabel(app.GridLayout4);
            app.ORLabel.Text = 'OR';
            app.RecordAudioButton = uibutton(app.GridLayout4, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.RecordDurationSpinner = uispinner(app.GridLayout4);
            app.RecordDurationSpinner.Limits = [1 60];
            app.RecordDurationSpinner.Placeholder = 'Duration (s)';
            app.RecordDurationSpinner.Value = 5;
            app.AudioInfoLabel = uilabel(app.GridLayout4);
            app.AudioInfoLabel.Text = 'Duration: --s, Fs: --Hz, Channels: --';
            app.PlayAudioButton = uibutton(app.GridLayout4, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play';
            app.StopAudioButton = uibutton(app.GridLayout4, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop';
            app.AnalysisPanel = uipanel(app.GridLayout3);
            app.AnalysisPanel.Title = 'Sound Analysis';
            app.GridLayout5 = uigridlayout(app.AnalysisPanel);
            app.SpectrogramAxes = uiaxes(app.GridLayout5);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'X')
            ylabel(app.SpectrogramAxes, 'Y')
            zlabel(app.SpectrogramAxes, 'Z')
            app.ColormapDropDownLabel = uilabel(app.GridLayout5);
            app.ColormapDropDownLabel.Text = 'Colormap:';
            app.ColormapDropDown = uidropdown(app.GridLayout5);
            app.ColormapDropDown.Items = {'parula', 'jet', 'hot', 'cool', 'gray', 'pink'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged2, true);
            app.ColormapDropDown.Value = 'parula';
            app.AnalyzeSoundButton = uibutton(app.GridLayout5, 'push');
            app.AnalyzeSoundButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSoundButtonPushed, true);
            app.AnalyzeSoundButton.Text = 'Analyse Sound';
            app.DetectionTitleLabel = uilabel(app.GridLayout5);
            app.DetectionTitleLabel.Text = 'Threat Detection:';
            app.DetectionResultLabel = uilabel(app.GridLayout5);
            app.DetectionResultLabel.Text = '---------';
            app.ConfidenceTitleLabel = uilabel(app.GridLayout5);
            app.ConfidenceTitleLabel.Text = 'Confidence Level:';
            app.ConfidenceGauge = uigauge(app.GridLayout5, 'circular');
            app.ThreatLevelLabel = uilabel(app.GridLayout5);
            app.ThreatLevelLabel.Text = 'Threat Level:';
            app.ThreatIndicatorLamp = uilamp(app.GridLayout5);
            app.SaveSnapshotButton = uibutton(app.GridLayout5, 'push');
            app.SaveSnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSnapshotButtonPushed, true);
            app.SaveSnapshotButton.Text = 'Save Snapshot';
            app.ClearButton = uibutton(app.GridLayout5, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear All';
            app.EvaluationTab = uitab(app.MainTabGroup);
            app.EvaluationTab.Title = 'Evaluation';
            app.Panel_17 = uipanel(app.EvaluationTab);
            app.SVMLabel = uilabel(app.Panel_17);
            app.SVMLabel.Text = 'SVM';
            app.SVMAccuracyLabel = uilabel(app.Panel_17);
            app.SVMAccuracyLabel.Text = 'SVMAccuracyLabel';
            app.SVMAccuracyGauge = uigauge(app.Panel_17, 'circular');
            app.SVMPrecisionLabel = uilabel(app.Panel_17);
            app.SVMPrecisionLabel.Text = 'SVMPrecision';
            app.Panel_12 = uipanel(app.EvaluationTab);
            app.RandomForestLabel = uilabel(app.Panel_12);
            app.RandomForestLabel.Text = 'Random Forest';
            app.RandomForestAccuracyGauge = uigauge(app.Panel_12, 'circular');
            app.RandomForestAccuracyLabel = uilabel(app.Panel_12);
            app.RandomForestAccuracyLabel.Text = 'RandomForestAccuracyLabel';
            app.Panel_13 = uipanel(app.EvaluationTab);
            app.CNNLabel = uilabel(app.Panel_13);
            app.CNNLabel.Text = '         CNN';
            app.CNNAccuracyGauge = uigauge(app.Panel_13, 'circular');
            app.CNNAccuracyLabel = uilabel(app.Panel_13);
            app.CNNAccuracyLabel.Text = 'CNNAccuracyLabel';
            app.Panel_14 = uipanel(app.EvaluationTab);
            app.KNNLabel = uilabel(app.Panel_14);
            app.KNNLabel.Text = '         KNN';
            app.KNNAccuracyGaugeLabel = uilabel(app.Panel_14);
            app.KNNAccuracyGaugeLabel.Text = 'KNNAccuracyGauge';
            app.KNNAccuracyGauge = uigauge(app.Panel_14, 'circular');
            app.KNNAccuracyLabel = uilabel(app.Panel_14);
            app.KNNAccuracyLabel.Text = 'KNNAccuracyLabel';
            app.Panel_15 = uipanel(app.EvaluationTab);
            app.Panel_16 = uipanel(app.EvaluationTab);
            app.EvaluateButton = uibutton(app.EvaluationTab, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.EventLogTab = uitab(app.MainTabGroup);
            app.EventLogTab.Title = 'Event Log';
            app.GridLayout6 = uigridlayout(app.EventLogTab);
            app.EventLogsLabel = uilabel(app.GridLayout6);
            app.EventLogsLabel.Text = 'Event Logs';
            app.LogTextArea = uitextarea(app.GridLayout6);
            app.ClearLogButton = uibutton(app.GridLayout6, 'push');
            app.ClearLogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogButtonPushed2, true);
            app.ClearLogButton.Text = 'Clear Log';
            app.ExportLogButton = uibutton(app.GridLayout6, 'push');
            app.ExportLogButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLogButtonPushed2, true);
            app.ExportLogButton.Text = 'Export Log';
            app.SettingsTab = uitab(app.MainTabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout7 = uigridlayout(app.SettingsTab);
            app.ModelSelectionPanel = uipanel(app.GridLayout7);
            app.ModelSelectionPanel.Title = 'Model Selection';
            app.ButtonGroup = uibuttongroup(app.ModelSelectionPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AutoselectbestmodelButton_2 = uiradiobutton(app.ButtonGroup);
            app.AutoselectbestmodelButton_2.Text = {'Autoselect best model'; ''; ''};
            app.AutoselectbestmodelButton_2.Value = true;
            app.SelectManuallyButton = uiradiobutton(app.ButtonGroup);
            app.SelectManuallyButton.Text = 'Select Manually';
            app.AvailableModelsLabel = uilabel(app.ModelSelectionPanel);
            app.AvailableModelsLabel.Text = 'Available Models :';
            app.ButtonGroup_2 = uibuttongroup(app.ModelSelectionPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.SVMButton = uiradiobutton(app.ButtonGroup_2);
            app.SVMButton.Text = 'SVM';
            app.SVMButton.Value = true;
            app.RandomForestButton = uiradiobutton(app.ButtonGroup_2);
            app.RandomForestButton.Text = 'RandomForest';
            app.CNNButton = uiradiobutton(app.ButtonGroup_2);
            app.CNNButton.Text = 'CNN';
            app.LSTMButton = uiradiobutton(app.ButtonGroup_2);
            app.LSTMButton.Text = 'LSTM';
            app.KNNButton = uiradiobutton(app.ButtonGroup_2);
            app.KNNButton.Text = 'KNN';
            app.ThresholdAccuracyPanel = uipanel(app.GridLayout7);
            app.ThresholdAccuracyPanel.Title = 'Threshold Accuracy';
            app.ButtonGroup_3 = uibuttongroup(app.ThresholdAccuracyPanel);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.Autoselect80Button = uiradiobutton(app.ButtonGroup_3);
            app.Autoselect80Button.Text = 'Autoselect (>80%)';
            app.Autoselect80Button.Value = true;
            app.SelectManuallyButton_2 = uiradiobutton(app.ButtonGroup_3);
            app.SelectManuallyButton_2.Text = 'Select Manually';
            app.ThresholdValueLabel = uilabel(app.ButtonGroup_3);
            app.ThresholdValueLabel.Text = 'Threshold Value :';
            app.ThresholdValueSpinner = uispinner(app.ButtonGroup_3);
            app.ResettoDefaultsButton = uibutton(app.ThresholdAccuracyPanel, 'push');
            app.ResettoDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultsButtonPushed, true);
            app.ResettoDefaultsButton.Text = 'Reset to default';
            app.SaveSettingsButton = uibutton(app.ThresholdAccuracyPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.AboutTab = uitab(app.MainTabGroup);
            app.AboutTab.Title = 'About ';
            app.GridLayout8 = uigridlayout(app.AboutTab);
            app.TabGroup = uitabgroup(app.GridLayout8);
            app.Home = uitab(app.TabGroup);
            app.Home.Title = 'Home';
            app.AuraSieldLabel = uilabel(app.Home);
            app.AuraSieldLabel.Text = 'AuraSield';
            app.EditField = uieditfield(app.Home, 'text');
            app.EditField.Value = '            Advanced acoustic monitoring solution developed for DRDO using cutting-edge MATLAB algorithms';
            app.LearnMoreAboutTheProjectButton = uibutton(app.Home, 'push');
            app.LearnMoreAboutTheProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LearnMoreAboutTheProjectButtonPushed, true);
            app.LearnMoreAboutTheProjectButton.Text = 'Learn More About The Project';
            app.CoreCapabilitiesLabel_3 = uilabel(app.Home);
            app.CoreCapabilitiesLabel_3.Text = 'Core Capabilities';
            app.Panel_9 = uipanel(app.Home);
            app.RealtimeProcessingLabel_3 = uilabel(app.Panel_9);
            app.RealtimeProcessingLabel_3.Text = 'Real-time Processing';
            app.AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel_3 = uilabel(app.Panel_9);
            app.AdvancedMATLABalgorithmsforcontinuousacousticmonitoringandanalysisLabel_3.Text = 'Advanced MATLAB algorithms for continuous acoustic monitoring and analysis';
            app.Panel_8 = uipanel(app.Home);
            app.ThreatDetectionLabel_3 = uilabel(app.Panel_8);
            app.ThreatDetectionLabel_3.Text = 'Threat Detection';
            app.IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel_3 = uilabel(app.Panel_8);
            app.IntelligentpatternrecognitionforidentifyingsecuritythreatsandintrusionsLabel_3.Text = 'Intelligent pattern recognition for identifying security threats and intrusions';
            app.Panel_7 = uipanel(app.Home);
            app.AnalyticsReportingLabel_3 = uilabel(app.Panel_7);
            app.AnalyticsReportingLabel_3.Text = 'Analytics & Reporting';
            app.ComprehensivedataanalysisandvisulizationforsecurityassessmentLabel_3 = uilabel(app.Panel_7);
            app.ComprehensivedataanalysisandvisulizationforsecurityassessmentLabel_3.Text = 'Comprehensive data analysis and visulization for security assessment';
            app.Panel_10 = uipanel(app.Home);
            app.ConfigurableParametersLabel = uilabel(app.Panel_10);
            app.ConfigurableParametersLabel.Text = 'Configurable Parameters';
            app.UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel = uilabel(app.Panel_10);
            app.UserdefinedthresholdsandfilteringoptionsforoptimalperformanceLabel.Text = 'User defined thresholds and filtering options for optimal performance';
            app.SoundIntrusionSystemLabel = uilabel(app.Home);
            app.SoundIntrusionSystemLabel.Text = 'Sound Intrusion System';
            app.AboutProject = uitab(app.TabGroup);
            app.AboutProject.Title = 'AboutProject';
            app.PROJECTHIGHLIGHTSPanel = uipanel(app.AboutProject);
            app.PROJECTHIGHLIGHTSPanel.Title = '                                             PROJECT HIGHLIGHTS                                                      ';
            app.GridLayout9 = uigridlayout(app.PROJECTHIGHLIGHTSPanel);
            app.MATLABbasedimplementationforrobustsignalprocessingLabel = uilabel(app.GridLayout9);
            app.MATLABbasedimplementationforrobustsignalprocessingLabel.Text = '● MATLAB-based implementation for robust signal processing';
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel = uilabel(app.GridLayout9);
            app.RealtimevisualizationofacousticdataandthreatlevelsLabel.Text = '● Real-time visualization of acoustic data and threat levels';
            app.ComprehensiveloggingandaudittrailfunctionalityLabel = uilabel(app.GridLayout9);
            app.ComprehensiveloggingandaudittrailfunctionalityLabel.Text = '● Comprehensive logging and audit trail functionality';
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel = uilabel(app.GridLayout9);
            app.MachinelearningalgorithmsforimproveddetectionaccuracyLabel.Text = '● Machine learning algorithms for improved detection accuracy';
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel = uilabel(app.GridLayout9);
            app.IntegrationcapabilitieswithexistingsecurityinfrastructureLabel.Text = '● Integration capabilities with existing security infrastructure';
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel = uilabel(app.GridLayout9);
            app.ScalablearchitecturesupportingmultiplesensornetworksLabel.Text = ' ● Scalable architecture supporting multiple sensor networks';
            app.DEVELOPMENTTEAMPanel = uipanel(app.AboutProject);
            app.DEVELOPMENTTEAMPanel.Title = 'DEVELOPMENT TEAM';
            app.GridLayout10 = uigridlayout(app.DEVELOPMENTTEAMPanel);
            app.AnshitaKapkotiLabel = uilabel(app.GridLayout10);
            app.AnshitaKapkotiLabel.Text = 'Anshita Kapkoti';
            app.AyushiMishraLabel = uilabel(app.GridLayout10);
            app.AyushiMishraLabel.Text = 'Ayushi Mishra';
            app.TejasPalLabel = uilabel(app.GridLayout10);
            app.TejasPalLabel.Text = 'Tejas Pal';
            app.AdityaRajSrivastavaLabel = uilabel(app.GridLayout10);
            app.AdityaRajSrivastavaLabel.Text = 'Aditya Raj Srivastava';
            app.AmitanshSrivastavaLabel = uilabel(app.GridLayout10);
            app.AmitanshSrivastavaLabel.Text = 'Amitansh Srivastava';
            app.PriyanshuSrivastavaLabel = uilabel(app.GridLayout10);
            app.PriyanshuSrivastavaLabel.Text = 'Priyanshu Srivastava';
            app.ComparisonTab = uitab(app.MainTabGroup);
            app.ComparisonTab.Title = 'Comparison';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drdoproject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end